classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MiscellaneousParametersTab      matlab.ui.container.Tab
        IntegerEditField                matlab.ui.control.NumericEditField
        IntegerEditFieldLabel           matlab.ui.control.Label
        FractionEditField               matlab.ui.control.NumericEditField
        FractionEditFieldLabel          matlab.ui.control.Label
        SubmitParametersButton_2        matlab.ui.control.Button
        ArithmaticTypeButtonGroup       matlab.ui.container.ButtonGroup
        FixedButton                     matlab.ui.control.RadioButton
        FloatButton_2                   matlab.ui.control.RadioButton
        InputTypeButtonGroup            matlab.ui.container.ButtonGroup
        GrayscaleButton                 matlab.ui.control.RadioButton
        RGBButton                       matlab.ui.control.RadioButton
        DatawidthEditField              matlab.ui.control.NumericEditField
        DatawidthEditFieldLabel         matlab.ui.control.Label
        MantissaEditField               matlab.ui.control.NumericEditField
        MantissaEditFieldLabel          matlab.ui.control.Label
        ExponentEditField               matlab.ui.control.NumericEditField
        ExponentEditFieldLabel          matlab.ui.control.Label
        InputSizeEditField              matlab.ui.control.NumericEditField
        InputSizeEditFieldLabel         matlab.ui.control.Label
        OutputfilerelativepathTextAreaLabel  matlab.ui.control.Label
        OutputfilerelativepathTextArea  matlab.ui.control.TextArea
        UITable_2                       matlab.ui.control.Table
        ArchitectureTab                 matlab.ui.container.Tab
        SoftmaxlayerPanel               matlab.ui.container.Panel
        SubmitlayerButton_7             matlab.ui.control.StateButton
        ConvWithDCTParametersPanel_2    matlab.ui.container.Panel
        NoofunitsEditField_4            matlab.ui.control.NumericEditField
        NoofunitsEditField_4Label       matlab.ui.control.Label
        OutWidthEditField_2             matlab.ui.control.NumericEditField
        OutWidthEditField_2Label        matlab.ui.control.Label
        CompressionRatioEditField_2     matlab.ui.control.NumericEditField
        CompressionRatioEditField_2Label  matlab.ui.control.Label
        StrideEditField_5               matlab.ui.control.NumericEditField
        StrideEditField_5Label          matlab.ui.control.Label
        KernalSizeEditField_5           matlab.ui.control.NumericEditField
        KernalSizeEditField_5Label      matlab.ui.control.Label
        SubmitlayerButton_6             matlab.ui.control.StateButton
        NoofFiltersEditField_4          matlab.ui.control.NumericEditField
        NoofFiltersEditField_4Label     matlab.ui.control.Label
        ConvWithDCTParametersPanel      matlab.ui.container.Panel
        OutWidthEditField               matlab.ui.control.NumericEditField
        OutWidthEditFieldLabel          matlab.ui.control.Label
        CompressionRatioEditField       matlab.ui.control.NumericEditField
        CompressionRatioEditFieldLabel  matlab.ui.control.Label
        StrideEditField_4               matlab.ui.control.NumericEditField
        StrideEditField_4Label          matlab.ui.control.Label
        KernalSizeEditField_4           matlab.ui.control.NumericEditField
        KernalSizeEditField_4Label      matlab.ui.control.Label
        SubmitlayerButton_5             matlab.ui.control.StateButton
        NoofunitsEditField_3            matlab.ui.control.NumericEditField
        NoofunitsEditField_3Label       matlab.ui.control.Label
        NoofFiltersEditField_3          matlab.ui.control.NumericEditField
        NoofFiltersEditField_3Label     matlab.ui.control.Label
        SubmitArchitectureButton_2      matlab.ui.control.Button
        ConvolutionParametersPanel      matlab.ui.container.Panel
        NoofunitsEditField              matlab.ui.control.NumericEditField
        NoofunitsEditFieldLabel         matlab.ui.control.Label
        StrideEditField                 matlab.ui.control.NumericEditField
        StrideEditFieldLabel            matlab.ui.control.Label
        NoofFiltersEditField            matlab.ui.control.NumericEditField
        NoofFiltersEditFieldLabel       matlab.ui.control.Label
        KernalSizeEditField             matlab.ui.control.NumericEditField
        KernalSizeEditFieldLabel        matlab.ui.control.Label
        SubmitlayerButton_3             matlab.ui.control.StateButton
        AveragepoolingParametersPanel   matlab.ui.container.Panel
        SubmitlayerButton_4             matlab.ui.control.StateButton
        NoofunitsEditField_2            matlab.ui.control.NumericEditField
        NoofunitsEditField_2Label       matlab.ui.control.Label
        StrideEditField_2               matlab.ui.control.NumericEditField
        StrideEditField_2Label          matlab.ui.control.Label
        KernalSizeEditField_3           matlab.ui.control.NumericEditField
        KernalSizeEditField_3Label      matlab.ui.control.Label
        FullyconnectedParametersPanel   matlab.ui.container.Panel
        NoofMultipliersEditField        matlab.ui.control.NumericEditField
        NoofMultipliersEditFieldLabel   matlab.ui.control.Label
        NoofFiltersEditField_2          matlab.ui.control.NumericEditField
        NoofFiltersEditField_2Label     matlab.ui.control.Label
        SubmitlayerButton_2             matlab.ui.control.StateButton
        LayersButtonGroup               matlab.ui.container.ButtonGroup
        SoftmaxButton                   matlab.ui.control.ToggleButton
        ConvWithDCTButton               matlab.ui.control.ToggleButton
        FullyconnectedButton            matlab.ui.control.ToggleButton
        AveragepoolingButton            matlab.ui.control.ToggleButton
        ConvolutionButton               matlab.ui.control.ToggleButton
        UITable                         matlab.ui.control.Table
        OutputFilesTab                  matlab.ui.container.Tab
        GenerateButton                  matlab.ui.control.Button
        StatusLamp                      matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        CodeSampleTextArea              matlab.ui.control.TextArea
        CodeSampleTextAreaLabel         matlab.ui.control.Label
        UpdatelistButton                matlab.ui.control.Button
        GeneratedfilesListBox           matlab.ui.control.ListBox
        GeneratedfilesListBoxLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        layers_table % Description
        param_table % Description
        Table2_handler % Description
        aorb % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            c1 = ["Data width"; "Mantissa";"Exponent";"Integer";"Fraction";"Input size";"Input type";"Arithmatic type";"Output files path"];
            c2 = [0;0;0;0;0;0;"RGB";"Float";"-"];
            app.UITable_2.Data = [c1 c2];
            app.aorb = 0;
        end

        % Value changed function: SubmitlayerButton_3
        function SubmitlayerButton_3ValueChanged(app, event)
            app.aorb = app.aorb + 1;
            app.layers_table = app.UITable.Data;
            kernal_size = app.KernalSizeEditField.Value;
            no_filters = app.NoofFiltersEditField.Value;
            no_units = app.NoofunitsEditField.Value;
            stride = app.StrideEditField.Value;
            compression_ratio = char("N/A");
            out_width = char("N/A");
            number_of_multipliers = char("N/A");
            if mod(app.aorb,2) == 1
                layer_name = char("conva");
            else
                layer_name = char("convb");
            end
            
            row_element = {layer_name kernal_size no_filters stride no_units compression_ratio out_width number_of_multipliers} ;
            app.UITable.Data = [app.layers_table ;row_element];
            
        end

        % Selection changed function: LayersButtonGroup
        function LayersButtonGroupSelectionChanged(app, event)
            selectedButton = app.LayersButtonGroup.SelectedObject;
            if(selectedButton == app.FullyconnectedButton)
                app.ConvolutionParametersPanel.Visible = 0;
                app.AveragepoolingParametersPanel.Visible = 0;
                app.FullyconnectedParametersPanel.Visible = 1;
                app.ConvWithDCTParametersPanel.Visible = 0;
                app.SoftmaxlayerPanel.Visible = 0;
            end
            if(selectedButton == app.AveragepoolingButton)
                app.ConvolutionParametersPanel.Visible = 0;
                app.AveragepoolingParametersPanel.Visible = 1;
                app.FullyconnectedParametersPanel.Visible = 0;
                app.ConvWithDCTParametersPanel.Visible = 0;
                app.SoftmaxlayerPanel.Visible = 0;
            end
            if(selectedButton == app.ConvolutionButton)
                app.ConvolutionParametersPanel.Visible = 1;
                app.AveragepoolingParametersPanel.Visible = 0;
                app.FullyconnectedParametersPanel.Visible = 0;
                app.ConvWithDCTParametersPanel.Visible = 0;
                app.SoftmaxlayerPanel.Visible = 0;
            end
            if(selectedButton == app.ConvWithDCTButton)
                app.ConvolutionParametersPanel.Visible = 0;
                app.AveragepoolingParametersPanel.Visible = 0;
                app.FullyconnectedParametersPanel.Visible = 0;
                app.ConvWithDCTParametersPanel.Visible = 1;
                app.SoftmaxlayerPanel.Visible = 0;
            end
            if(selectedButton == app.SoftmaxButton)
                app.ConvolutionParametersPanel.Visible = 0;
                app.AveragepoolingParametersPanel.Visible = 0;
                app.FullyconnectedParametersPanel.Visible = 0;
                app.ConvWithDCTParametersPanel.Visible = 0;
                app.SoftmaxlayerPanel.Visible = 1;
            end
        end

        % Value changed function: SubmitlayerButton_4
        function SubmitlayerButton_4ValueChanged(app, event)
            app.layers_table = app.UITable.Data;
            kernal_size = app.KernalSizeEditField_3.Value;
            no_filters = char("N/A");
            no_units = app.NoofunitsEditField_2.Value;
            stride = app.StrideEditField_2.Value;
            compression_ratio = char("N/A");
            out_width = char("N/A");
            number_of_multipliers = char("N/A");
            layer_name = char("pool");
            row_element = {layer_name kernal_size no_filters stride no_units compression_ratio out_width number_of_multipliers} ;
            app.UITable.Data = [app.layers_table ;row_element];
        end

        % Value changed function: SubmitlayerButton_2
        function SubmitlayerButton_2ValueChanged(app, event)
            app.layers_table = app.UITable.Data;
            kernal_size = char("N/A");
            no_filters = app.NoofFiltersEditField_2.Value;
            no_units = char("N/A");
            stride = char("N/A");
            compression_ratio = char("N/A");
            out_width = char("N/A");
            number_of_multipliers = app.NoofMultipliersEditField.Value; 
            layer_name = char("FC");
            row_element = {layer_name kernal_size no_filters stride no_units compression_ratio out_width number_of_multipliers} ;
            app.UITable.Data = [app.layers_table ;row_element];
        end

        % Value changed function: DatawidthEditField
        function DatawidthEditFieldValueChanged(app, event)
            
 
            value = app.DatawidthEditField.Value;

            app.UITable_2.Data(1,2) = value;
            
            
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            %msgbox (pwd);
            %Populate listbox with file names:
            app.StatusLamp.Color = [1 0 0];
            app.StatusLampLabel.Text = "Running...";
            drawnow();
            system("cd Python && python CNN_generator.py");
            %system("python CNN_generator.py");
            
            
            app.StatusLamp.Color = [0 1 0];
            app.StatusLampLabel.Text = "Done";
            
            
        end

        % Button pushed function: UpdatelistButton
        function UpdatelistButtonPushed(app, event)
            path = app.OutputfilerelativepathTextArea.Value;
            path = strcat(".\Python\",path);
            v_files = dir(fullfile(path, '*.v')); %Dir all *.gpx in selected folder.
            app.GeneratedfilesListBox.Items = {v_files(:).name};
            drawnow();
            %end
            
        end

        % Value changed function: GeneratedfilesListBox
        function GeneratedfilesListBoxValueChanged(app, event)
            value = app.GeneratedfilesListBox.Value;
            path = app.OutputfilerelativepathTextArea.Value;
            path = strcat(".\Python\",path);
            path = strcat(path, "\");
            file_name = strcat(path,value);
            app.CodeSampleTextArea.Value =  fileread(file_name);
        end

        % Selection changed function: InputTypeButtonGroup
        function InputTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.InputTypeButtonGroup.SelectedObject;
            if selectedButton == app.GrayscaleButton
                app.UITable_2.Data(7,2) = ["GRAY SCALE"];
            else
                app.UITable_2.Data(7,2) = ["RGB"];
            end
        end

        % Selection changed function: ArithmaticTypeButtonGroup
        function ArithmaticTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ArithmaticTypeButtonGroup.SelectedObject;
            if selectedButton == app.FixedButton
                app.UITable_2.Data(8,2) = ["Float"];
                app.UITable_2.Data(2, 1) = ["Mantissa"];
                app.UITable_2.Data(3, 1) = ["Exponent"];
                app.UITable_2.Data(4, 1) = ["Integer"];
                app.UITable_2.Data(5, 1) = ["Fraction"];
                app.MantissaEditFieldLabel.Text = "Mantissa";
                app.ExponentEditFieldLabel.Text = "Exponent";
                app.IntegerEditFieldLabel.Text = "Integer";
                app.FractionEditFieldLabel.Text = "Fraction";
                
            else
                app.UITable_2.Data(8,2) = ["Float"];
                app.UITable_2.Data(2, 1) = ["Mantissa"];
                app.UITable_2.Data(3, 1) = ["Exponent"];
                app.UITable_2.Data(4, 1) = ["Integer"];
                app.UITable_2.Data(5, 1) = ["Fraction"];
                app.MantissaEditFieldLabel.Text = "Mantissa";
                app.ExponentEditFieldLabel.Text = "Exponent";
                app.IntegerEditFieldLabel.Text = "Integer";
                app.FractionEditFieldLabel.Text = "Fraction";
            end
        end

        % Button pushed function: SubmitParametersButton_2
        function SubmitParametersButton_2Pushed(app, event)
            %msgbox (pwd) ;
            selectedButton = app.InputTypeButtonGroup.SelectedObject;
            exel = app.UITable_2.Data;
            if selectedButton == app.GrayscaleButton
                exel(7, 2) = 0;
            else
                exel(7, 2) = 1;
            end
            
            selectedButton = app.ArithmaticTypeButtonGroup.SelectedObject;
            if selectedButton == app.FixedButton
                exel(8, 2) = 1;
            else
                exel(8, 2) = 0;
            end
            writematrix(exel,".\Python\AdvancedParameters_2.csv")
        end

        % Value changed function: MantissaEditField
        function MantissaEditFieldValueChanged(app, event)
            value = app.MantissaEditField.Value;
            app.UITable_2.Data(2,2) = value;
        end

        % Value changed function: ExponentEditField
        function ExponentEditFieldValueChanged(app, event)
            value = app.ExponentEditField.Value;
            app.UITable_2.Data(3,2) = value;
        end

        % Value changed function: InputSizeEditField
        function InputSizeEditFieldValueChanged(app, event)
            value = app.InputSizeEditField.Value;
            app.UITable_2.Data(6,2) = value;
        end

        % Value changed function: OutputfilerelativepathTextArea
        function OutputfilerelativepathTextAreaValueChanged(app, event)
            value = app.OutputfilerelativepathTextArea.Value;
            app.UITable_2.Data(9,2) = value;
        end

        % Button pushed function: SubmitArchitectureButton_2
        function SubmitArchitectureButton_2Pushed(app, event)
            t = app.UITable.Data ;
            disp("app is")
            disp(t)
            %fprintf('size(t,1) = \n')
            disp(size(t,1)) %% SIZE (t,1) = # of layers used
            exel_size = (size(t,1)*10); %% size of csv file = 10*#of used layers
            %fprintf('exel_size = \n')
            %disp(exel_size)
            
            exel = cell([exel_size, 2]); %% excell consisting of 1 column conc with 2 columns with names and values , #of rows = size of csv file
            
            %fprintf('size(t,1) = \n')
            %disp(size(t,1))
            fprintf('size(exel) = \n')
            disp(size(exel)) %% will give 10*#layers       #layers
            %fprintf('exel = \n') 
            %disp(exel)
            
            input_size = app.InputSizeEditField.Value; %%given input size in parameters
            %fprintf('input_size = \n')
            %disp(input_size)
            
            field_layer = 0;
            for layer = 1:size(t,1) %% layer from 1 : number of layers
                
                field_layer = field_layer + 1;%layer name ##field layer = 1
                exel((layer+field_layer-1 ), 1) = cellstr("layer name"); %%excel (1,1) = layer name
                layer_name = sprintf("%s%d", t{layer, 1}, layer); 
                exel(layer+field_layer-1, 2) = cellstr(layer_name); %%conva and number of layer(conva1)
                field_layer = field_layer + 1;%kernal size %%field = 2
                exel(layer+field_layer-1, 1) = cellstr("kernal Size"); %%excel(2,1) = kernal size
                if(strcmp(t(layer,2),cellstr("N/A")) == 1)
                    exel(layer+field_layer-1, 2) = {1}; %% if kernal = N/A store 1
                else
                    exel(layer+field_layer-1, 2) = t(layer,2); %% else store kernal size
                end
                
                field_layer = field_layer + 1;%stride
                exel(layer+field_layer-1, 1) = cellstr("Stride");
                if(strcmp(t(layer,2),cellstr("N/A")) == 1) 
                    exel(layer+field_layer-1, 2) = {1}; %% if stride = N/A STORE 1
                else
                    exel(layer+field_layer-1, 2) = t(layer,4);
                end
                
                field_layer = field_layer + 1;%input size
                exel(layer+field_layer-1, 1) = cellstr("Input Size");
                if(strcmp(t(layer,2),cellstr("N/A")) == 1)
                    
                    exel(layer+field_layer-1, 2) = {1}; %% IF INPUT SIZE = N/A store 1
                    
                else
                    exel(layer+field_layer-1, 2) = {input_size}; % else store input_size which is initially given in parameter of input img
                    input_size = ((input_size - t{layer,2})/t{layer,4}) + 1; %% input of next img is (input-kernal/stride) +1
                    
                end
                field_layer = field_layer + 1;%input channels
                exel(layer+field_layer-1, 1) = cellstr("input channels");
                if(layer == 1)
                    if(app.InputTypeButtonGroup.SelectedObject == app.GrayscaleButton)
                        exel(layer+field_layer-1, 2) = {1};
                    elseif(app.InputTypeButtonGroup.SelectedObject == app.RGBButton)
                        exel(layer+field_layer-1, 2) = {3};
                    end
                    
                else
                    exel(layer+field_layer-1, 2) = t(layer-1,3); %% input channels of layer is # of filters of previous layer
                end
                
                field_layer = field_layer + 1;%filters
                exel(layer+field_layer-1, 1) = cellstr("No of filters");
                if(strcmp(t(layer,3),cellstr("N/A")) == 1)
                    t((layer),3) = t((layer-1),3);
                    exel(layer+field_layer-1, 2) = t((layer-1),3);
                else
                    exel(layer+field_layer-1, 2) = t(layer,3);
                end
                field_layer = field_layer + 1;%units
                exel(layer+field_layer-1, 1) = cellstr("No of units");
                if(strcmp(t(layer,2),cellstr("N/A")) == 1)
                    exel(layer+field_layer-1, 2) = t((layer),3);
                else
                    exel(layer+field_layer-1, 2) = t(layer,5);
                end
                
                field_layer = field_layer + 1; %Compression Ratio
                exel(layer+field_layer-1, 1) = cellstr("Compression Ratio");
                if(strcmp(t(layer,6),cellstr("N/A")) == 1) 
                    exel(layer+field_layer-1, 2) = {"N/A"}; %% if comp = N/A STORE 1
                else
                    exel(layer+field_layer-1, 2) = t(layer,6);
                end
                %disp(t(layer,6))
                field_layer = field_layer + 1; %Out Width
                exel(layer+field_layer-1, 1) = cellstr("Out Width");
                if(strcmp(t(layer,7),cellstr("N/A")) == 1) 
                    exel(layer+field_layer-1, 2) = {"N/A"}; %% if out_width = N/A STORE 1
                else
                    exel(layer+field_layer-1, 2) = t(layer,7);
                end                
                %disp (t(layer,6))
                field_layer = field_layer + 1; %Number of multipliers used in FC
                exel(layer+field_layer-1, 1) = cellstr("Number of Multipliers");
                if(strcmp(t(layer,8),cellstr("N/A")) == 1) 
                    exel(layer+field_layer-1, 2) = {"N/A"}; %% if mul_num = N/A STORE 1
                else
                    exel(layer+field_layer-1, 2) = t(layer,8);
                end  


                field_layer = field_layer - 1;
            end

            writecell(exel,".\Python\Architecture_2.csv")
            %app.TextArea.Value = t(1,1);
            %writematrix(t,"test.csv");
        end

        % Value changed function: IntegerEditField
        function IntegerEditFieldValueChanged(app, event)
            value = app.IntegerEditField.Value;
            app.UITable_2.Data(4,2) = value;
        end

        % Value changed function: FractionEditField
        function FractionEditFieldValueChanged(app, event)
            value = app.FractionEditField.Value;
            app.UITable_2.Data(5,2) = value;
        end

        % Value changed function: SubmitlayerButton_5
        function SubmitlayerButton_5ValueChanged(app, event)
            app.aorb = app.aorb + 1;
            app.layers_table = app.UITable.Data;
            kernal_size = app.KernalSizeEditField_4.Value;
            no_filters = app.NoofFiltersEditField_3.Value;
            no_units = app.NoofunitsEditField_3.Value;
            stride = app.StrideEditField_4.Value;
            compression_ratio = app.CompressionRatioEditField.Value;
            out_width = app.OutWidthEditField.Value  ; 
            number_of_multipliers = char("N/A");
            if mod(app.aorb,2) == 1
                layer_name = char("convaWithDCT");
            else
                layer_name = char("convbWithDCT");
            end
            
            row_element = {layer_name kernal_size no_filters stride no_units compression_ratio out_width number_of_multipliers} ;
            app.UITable.Data = [app.layers_table ;row_element];
        end

        % Value changed function: SubmitlayerButton_7
        function SubmitlayerButton_7ValueChanged(app, event)
            app.layers_table = app.UITable.Data;
            kernal_size = char("N/A");
            no_filters = char("N/A");
            no_units = char("N/A");
            stride = char("N/A");
            compression_ratio = char("N/A");
            out_width = char("N/A");
            number_of_multipliers = app.NoofMultipliersEditField.Value ;
            layer_name = char("softmax");
            row_element = {layer_name kernal_size no_filters stride no_units compression_ratio out_width number_of_multipliers} ;
            app.UITable.Data = [app.layers_table ;row_element];
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MiscellaneousParametersTab = uitab(app.TabGroup);
            app.MiscellaneousParametersTab.Title = 'Miscellaneous Parameters';
            app.UITable_2 = uitable(app.MiscellaneousParametersTab);
            app.UITable_2.ColumnName = {'Parameter'; 'Value'};
            app.UITable_2.RowName = {};
            app.OutputfilerelativepathTextArea = uitextarea(app.MiscellaneousParametersTab);
            app.OutputfilerelativepathTextArea.ValueChangedFcn = createCallbackFcn(app, @OutputfilerelativepathTextAreaValueChanged, true);
            app.OutputfilerelativepathTextAreaLabel = uilabel(app.MiscellaneousParametersTab);
            app.OutputfilerelativepathTextAreaLabel.Text = 'Output file relative path';
            app.InputSizeEditFieldLabel = uilabel(app.MiscellaneousParametersTab);
            app.InputSizeEditFieldLabel.Text = 'Input Size';
            app.InputSizeEditField = uieditfield(app.MiscellaneousParametersTab, 'numeric');
            app.InputSizeEditField.ValueChangedFcn = createCallbackFcn(app, @InputSizeEditFieldValueChanged, true);
            app.ExponentEditFieldLabel = uilabel(app.MiscellaneousParametersTab);
            app.ExponentEditFieldLabel.Text = 'Exponent';
            app.ExponentEditField = uieditfield(app.MiscellaneousParametersTab, 'numeric');
            app.ExponentEditField.ValueChangedFcn = createCallbackFcn(app, @ExponentEditFieldValueChanged, true);
            app.MantissaEditFieldLabel = uilabel(app.MiscellaneousParametersTab);
            app.MantissaEditFieldLabel.Text = 'Mantissa';
            app.MantissaEditField = uieditfield(app.MiscellaneousParametersTab, 'numeric');
            app.MantissaEditField.ValueChangedFcn = createCallbackFcn(app, @MantissaEditFieldValueChanged, true);
            app.DatawidthEditFieldLabel = uilabel(app.MiscellaneousParametersTab);
            app.DatawidthEditFieldLabel.Text = 'Data width';
            app.DatawidthEditField = uieditfield(app.MiscellaneousParametersTab, 'numeric');
            app.DatawidthEditField.ValueChangedFcn = createCallbackFcn(app, @DatawidthEditFieldValueChanged, true);
            app.InputTypeButtonGroup = uibuttongroup(app.MiscellaneousParametersTab);
            app.InputTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InputTypeButtonGroupSelectionChanged, true);
            app.InputTypeButtonGroup.Title = 'Input Type';
            app.RGBButton = uiradiobutton(app.InputTypeButtonGroup);
            app.RGBButton.Text = 'RGB';
            app.RGBButton.Value = true;
            app.GrayscaleButton = uiradiobutton(app.InputTypeButtonGroup);
            app.GrayscaleButton.Text = 'Gray scale';
            app.ArithmaticTypeButtonGroup = uibuttongroup(app.MiscellaneousParametersTab);
            app.ArithmaticTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ArithmaticTypeButtonGroupSelectionChanged, true);
            app.ArithmaticTypeButtonGroup.Title = 'Arithmatic Type';
            app.FloatButton_2 = uiradiobutton(app.ArithmaticTypeButtonGroup);
            app.FloatButton_2.Text = 'Float';
            app.FloatButton_2.Value = true;
            app.FixedButton = uiradiobutton(app.ArithmaticTypeButtonGroup);
            app.FixedButton.Text = 'Fixed';
            app.SubmitParametersButton_2 = uibutton(app.MiscellaneousParametersTab, 'push');
            app.SubmitParametersButton_2.ButtonPushedFcn = createCallbackFcn(app, @SubmitParametersButton_2Pushed, true);
            app.SubmitParametersButton_2.Text = 'Submit Parameters';
            app.FractionEditFieldLabel = uilabel(app.MiscellaneousParametersTab);
            app.FractionEditFieldLabel.Text = 'Fraction';
            app.FractionEditField = uieditfield(app.MiscellaneousParametersTab, 'numeric');
            app.FractionEditField.ValueChangedFcn = createCallbackFcn(app, @FractionEditFieldValueChanged, true);
            app.IntegerEditFieldLabel = uilabel(app.MiscellaneousParametersTab);
            app.IntegerEditFieldLabel.Text = 'Integer';
            app.IntegerEditField = uieditfield(app.MiscellaneousParametersTab, 'numeric');
            app.IntegerEditField.ValueChangedFcn = createCallbackFcn(app, @IntegerEditFieldValueChanged, true);
            app.ArchitectureTab = uitab(app.TabGroup);
            app.ArchitectureTab.Title = 'Architecture';
            app.UITable = uitable(app.ArchitectureTab);
            app.UITable.ColumnName = {'Layer name'; 'Kernal'; 'Filters'; 'Stride'; 'Units'; 'Comp Ratio'; 'Out Width'; 'No of Mul'};
            app.UITable.RowName = {};
            app.LayersButtonGroup = uibuttongroup(app.ArchitectureTab);
            app.LayersButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LayersButtonGroupSelectionChanged, true);
            app.LayersButtonGroup.Title = 'Layers';
            app.ConvolutionButton = uitogglebutton(app.LayersButtonGroup);
            app.ConvolutionButton.Text = 'Convolution';
            app.ConvolutionButton.Value = true;
            app.AveragepoolingButton = uitogglebutton(app.LayersButtonGroup);
            app.AveragepoolingButton.Text = 'Average pooling';
            app.FullyconnectedButton = uitogglebutton(app.LayersButtonGroup);
            app.FullyconnectedButton.Text = 'Fully connected';
            app.ConvWithDCTButton = uitogglebutton(app.LayersButtonGroup);
            app.ConvWithDCTButton.Text = 'Conv With DCT';
            app.SoftmaxButton = uitogglebutton(app.LayersButtonGroup);
            app.SoftmaxButton.Text = 'Softmax';
            app.FullyconnectedParametersPanel = uipanel(app.ArchitectureTab);
            app.FullyconnectedParametersPanel.Title = 'Fully connected Parameters';
            app.FullyconnectedParametersPanel.Visible = 'off';
            app.SubmitlayerButton_2 = uibutton(app.FullyconnectedParametersPanel, 'state');
            app.SubmitlayerButton_2.ValueChangedFcn = createCallbackFcn(app, @SubmitlayerButton_2ValueChanged, true);
            app.SubmitlayerButton_2.Text = 'Submit layer';
            app.NoofFiltersEditField_2Label = uilabel(app.FullyconnectedParametersPanel);
            app.NoofFiltersEditField_2Label.Text = 'No of Filters';
            app.NoofFiltersEditField_2 = uieditfield(app.FullyconnectedParametersPanel, 'numeric');
            app.NoofMultipliersEditFieldLabel = uilabel(app.FullyconnectedParametersPanel);
            app.NoofMultipliersEditFieldLabel.Text = 'No of Multipliers';
            app.NoofMultipliersEditField = uieditfield(app.FullyconnectedParametersPanel, 'numeric');
            app.AveragepoolingParametersPanel = uipanel(app.ArchitectureTab);
            app.AveragepoolingParametersPanel.Title = 'Average pooling Parameters';
            app.AveragepoolingParametersPanel.Visible = 'off';
            app.KernalSizeEditField_3Label = uilabel(app.AveragepoolingParametersPanel);
            app.KernalSizeEditField_3Label.Text = 'Kernal Size';
            app.KernalSizeEditField_3 = uieditfield(app.AveragepoolingParametersPanel, 'numeric');
            app.StrideEditField_2Label = uilabel(app.AveragepoolingParametersPanel);
            app.StrideEditField_2Label.Text = 'Stride';
            app.StrideEditField_2 = uieditfield(app.AveragepoolingParametersPanel, 'numeric');
            app.NoofunitsEditField_2Label = uilabel(app.AveragepoolingParametersPanel);
            app.NoofunitsEditField_2Label.Text = 'No of units';
            app.NoofunitsEditField_2 = uieditfield(app.AveragepoolingParametersPanel, 'numeric');
            app.SubmitlayerButton_4 = uibutton(app.AveragepoolingParametersPanel, 'state');
            app.SubmitlayerButton_4.ValueChangedFcn = createCallbackFcn(app, @SubmitlayerButton_4ValueChanged, true);
            app.SubmitlayerButton_4.Text = 'Submit layer';
            app.ConvolutionParametersPanel = uipanel(app.ArchitectureTab);
            app.ConvolutionParametersPanel.Title = 'Convolution Parameters';
            app.SubmitlayerButton_3 = uibutton(app.ConvolutionParametersPanel, 'state');
            app.SubmitlayerButton_3.ValueChangedFcn = createCallbackFcn(app, @SubmitlayerButton_3ValueChanged, true);
            app.SubmitlayerButton_3.Text = 'Submit layer';
            app.KernalSizeEditFieldLabel = uilabel(app.ConvolutionParametersPanel);
            app.KernalSizeEditFieldLabel.Text = 'Kernal Size';
            app.KernalSizeEditField = uieditfield(app.ConvolutionParametersPanel, 'numeric');
            app.NoofFiltersEditFieldLabel = uilabel(app.ConvolutionParametersPanel);
            app.NoofFiltersEditFieldLabel.Text = 'No of Filters';
            app.NoofFiltersEditField = uieditfield(app.ConvolutionParametersPanel, 'numeric');
            app.StrideEditFieldLabel = uilabel(app.ConvolutionParametersPanel);
            app.StrideEditFieldLabel.Text = 'Stride';
            app.StrideEditField = uieditfield(app.ConvolutionParametersPanel, 'numeric');
            app.NoofunitsEditFieldLabel = uilabel(app.ConvolutionParametersPanel);
            app.NoofunitsEditFieldLabel.Text = 'No of units';
            app.NoofunitsEditField = uieditfield(app.ConvolutionParametersPanel, 'numeric');
            app.SubmitArchitectureButton_2 = uibutton(app.ArchitectureTab, 'push');
            app.SubmitArchitectureButton_2.ButtonPushedFcn = createCallbackFcn(app, @SubmitArchitectureButton_2Pushed, true);
            app.SubmitArchitectureButton_2.Text = 'Submit Architecture';
            app.ConvWithDCTParametersPanel = uipanel(app.ArchitectureTab);
            app.ConvWithDCTParametersPanel.Title = 'Conv With DCT Parameters';
            app.ConvWithDCTParametersPanel.Visible = 'off';
            app.NoofFiltersEditField_3Label = uilabel(app.ConvWithDCTParametersPanel);
            app.NoofFiltersEditField_3Label.Text = 'No of Filters';
            app.NoofFiltersEditField_3 = uieditfield(app.ConvWithDCTParametersPanel, 'numeric');
            app.NoofunitsEditField_3Label = uilabel(app.ConvWithDCTParametersPanel);
            app.NoofunitsEditField_3Label.Text = 'No of units';
            app.NoofunitsEditField_3 = uieditfield(app.ConvWithDCTParametersPanel, 'numeric');
            app.SubmitlayerButton_5 = uibutton(app.ConvWithDCTParametersPanel, 'state');
            app.SubmitlayerButton_5.ValueChangedFcn = createCallbackFcn(app, @SubmitlayerButton_5ValueChanged, true);
            app.SubmitlayerButton_5.Text = 'Submit layer';
            app.KernalSizeEditField_4Label = uilabel(app.ConvWithDCTParametersPanel);
            app.KernalSizeEditField_4Label.Text = 'Kernal Size';
            app.KernalSizeEditField_4 = uieditfield(app.ConvWithDCTParametersPanel, 'numeric');
            app.StrideEditField_4Label = uilabel(app.ConvWithDCTParametersPanel);
            app.StrideEditField_4Label.Text = 'Stride';
            app.StrideEditField_4 = uieditfield(app.ConvWithDCTParametersPanel, 'numeric');
            app.CompressionRatioEditFieldLabel = uilabel(app.ConvWithDCTParametersPanel);
            app.CompressionRatioEditFieldLabel.Text = 'Compression Ratio';
            app.CompressionRatioEditField = uieditfield(app.ConvWithDCTParametersPanel, 'numeric');
            app.OutWidthEditFieldLabel = uilabel(app.ConvWithDCTParametersPanel);
            app.OutWidthEditFieldLabel.Text = 'Out Width';
            app.OutWidthEditField = uieditfield(app.ConvWithDCTParametersPanel, 'numeric');
            app.ConvWithDCTParametersPanel_2 = uipanel(app.ArchitectureTab);
            app.ConvWithDCTParametersPanel_2.Title = 'Conv With DCT Parameters';
            app.ConvWithDCTParametersPanel_2.Visible = 'off';
            app.NoofFiltersEditField_4Label = uilabel(app.ConvWithDCTParametersPanel_2);
            app.NoofFiltersEditField_4Label.Text = 'No of Filters';
            app.NoofFiltersEditField_4 = uieditfield(app.ConvWithDCTParametersPanel_2, 'numeric');
            app.SubmitlayerButton_6 = uibutton(app.ConvWithDCTParametersPanel_2, 'state');
            app.SubmitlayerButton_6.Text = 'Submit layer';
            app.KernalSizeEditField_5Label = uilabel(app.ConvWithDCTParametersPanel_2);
            app.KernalSizeEditField_5Label.Text = 'Kernal Size';
            app.KernalSizeEditField_5 = uieditfield(app.ConvWithDCTParametersPanel_2, 'numeric');
            app.StrideEditField_5Label = uilabel(app.ConvWithDCTParametersPanel_2);
            app.StrideEditField_5Label.Text = 'Stride';
            app.StrideEditField_5 = uieditfield(app.ConvWithDCTParametersPanel_2, 'numeric');
            app.CompressionRatioEditField_2Label = uilabel(app.ConvWithDCTParametersPanel_2);
            app.CompressionRatioEditField_2Label.Text = 'Compression Ratio';
            app.CompressionRatioEditField_2 = uieditfield(app.ConvWithDCTParametersPanel_2, 'numeric');
            app.OutWidthEditField_2Label = uilabel(app.ConvWithDCTParametersPanel_2);
            app.OutWidthEditField_2Label.Text = 'Out Width';
            app.OutWidthEditField_2 = uieditfield(app.ConvWithDCTParametersPanel_2, 'numeric');
            app.NoofunitsEditField_4Label = uilabel(app.ConvWithDCTParametersPanel_2);
            app.NoofunitsEditField_4Label.Text = 'No of units';
            app.NoofunitsEditField_4 = uieditfield(app.ConvWithDCTParametersPanel_2, 'numeric');
            app.SoftmaxlayerPanel = uipanel(app.ArchitectureTab);
            app.SoftmaxlayerPanel.Title = 'Softmax layer';
            app.SoftmaxlayerPanel.Visible = 'off';
            app.SubmitlayerButton_7 = uibutton(app.SoftmaxlayerPanel, 'state');
            app.SubmitlayerButton_7.ValueChangedFcn = createCallbackFcn(app, @SubmitlayerButton_7ValueChanged, true);
            app.SubmitlayerButton_7.Text = 'Submit layer';
            app.OutputFilesTab = uitab(app.TabGroup);
            app.OutputFilesTab.Title = 'Output Files';
            app.GeneratedfilesListBoxLabel = uilabel(app.OutputFilesTab);
            app.GeneratedfilesListBoxLabel.Text = 'Generated files';
            app.GeneratedfilesListBox = uilistbox(app.OutputFilesTab);
            app.GeneratedfilesListBox.Items = {};
            app.GeneratedfilesListBox.ValueChangedFcn = createCallbackFcn(app, @GeneratedfilesListBoxValueChanged, true);
            app.GeneratedfilesListBox.Value = {};
            app.UpdatelistButton = uibutton(app.OutputFilesTab, 'push');
            app.UpdatelistButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatelistButtonPushed, true);
            app.UpdatelistButton.Text = 'Update list';
            app.CodeSampleTextAreaLabel = uilabel(app.OutputFilesTab);
            app.CodeSampleTextAreaLabel.Text = 'Code Sample';
            app.CodeSampleTextArea = uitextarea(app.OutputFilesTab);
            app.StatusLampLabel = uilabel(app.OutputFilesTab);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.OutputFilesTab);
            app.GenerateButton = uibutton(app.OutputFilesTab, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end